classdef DLSaveraging < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Panel                          matlab.ui.container.Panel
        TraceCountEditField            matlab.ui.control.NumericEditField
        TraceCountEditFieldLabel       matlab.ui.control.Label
        NoButton                       matlab.ui.control.Button
        YesButton                      matlab.ui.control.Button
        TextArea                       matlab.ui.control.TextArea
        ScatteringangleEditField       matlab.ui.control.NumericEditField
        ScatteringangleEditFieldLabel  matlab.ui.control.Label
        SelectfilesButton              matlab.ui.control.Button
        CHB                            matlab.ui.control.UIAxes
        CHA                            matlab.ui.control.UIAxes
        Corr                           matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        sum % Cell array for all summed values of g2 and channel columns
        data % Cell array holding all data columns of interest
        index % Index holding the number of selected files
        sz % Size of selected traces
    end
    
    methods (Access = private)
        
        function UserInput(app)
            app.Panel.Visible = 1;
            uiwait(app.UIFigure)
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectfilesButton
        function SelectfilesButtonPushed(app, event)
            app.TraceCountEditField.Value = 0;
            
            [file,path] = uigetfile('*.dat','Select one or more input files','MultiSelect','on');

            %Format to keep all decimal places
            format short

            % Set large possible index of end of file
            min = 1000000;
            app.sz = size(file,2);

            % Preallocate cell array for sum
            app.sum = cell(3,1);
            app.sum(:,1) = {0};

            if isa(file,"cell")
                for i = 1:1:size(file,2)
                    app.index = i;
                    % Open file and read all lines to create string w/ same format
                    default = readlines([path, file{1,i}]);

                    % Extract scattering angle for display
                    AngleTxt = regexp(default,'Scattering angle:	');     % Get cell array to see where Scattering angle is.
                    AngleInd = cellfun(@isempty,AngleTxt)==0;               % Make logical array to extract line index.
                    Angle = str2double(extractAfter(default(AngleInd),'Scattering angle:	'));
                    app.ScatteringangleEditField.Value = Angle;             % Display angle.

                    % Split at the line-breaks to find separated data blocks
                    % Create new cell matrix of strings, broken at each blank line
                    new = regexp(default,'\s\n\s','split');

                    % Find which lines are empty to get indices between blocks per file
                    logic = cellfun(@isempty,default);
                    indices = find(logic);

                    % Store smallest index of last line to administer cutoff and properly average the channels block
                    if indices(3) < min
                        min = indices(3);
                    end
                    
                    % Separate each block into its own cell array
                    app.data{1,i} = new(indices(1)+2:indices(2)-1,1);   % g2-1 data column
                    app.data{2,i} = new(indices(2)+2:indices(3)-1,1);   % Channel columns


                    %%Separate every element of each column into doubles to average later
                    %%Keep first column of both blocks untouched
                    
                    % Have both split blocks in new cell arrays containing
                    % the split cell arrays
                    for q = 1:2
                        splitCells{1,q} = cellfun(@split, app.data{q,i}, 'UniformOutput', false);
                    end

                    % Turn into doubles respective to each block's size
                    for indx = 1:1:size(splitCells{1,1})
                        lagTime(indx,1) = splitCells{1,1}{indx,1}(1);
                        g2(indx,1) = str2double(splitCells{1,1}{indx,1}(2));
                    end
                    
                    for indx = 1:1:size(splitCells{1,2})
                        countRate(indx,1) = splitCells{1,2}{indx,1}(1);
                        y2(indx,1) = str2double(splitCells{1,2}{indx,1}(2));
                        z2(indx,1) = str2double(splitCells{1,2}{indx,1}(3));
                    end

                    % Select the values we are averaging for 1st block
                    lag = lagTime(:);
                    app.data{1,i} = g2(:);
                    
                    % Select the values we are averaging for 2nd block
                    count = countRate(:);
                    app.data{2,i} = y2(:);
                    app.data{3,i} = z2(:);
                    
                    % Correlation plot
                    xvaluesPlot = str2double(lagTime);
                    plot(app.Corr,xvaluesPlot,app.data{1,i});
                    
                    % Separate channel plots
                    x2plot = str2double(countRate);
                    plot(app.CHA,x2plot,y2);
                    plot(app.CHB,x2plot,z2);

                    UserInput(app);

%                     % Pop up dialogue box
%                     selection = uiconfirm(app.UIFigure,'Do you want to add this trace to the final average?',...
%                         'Add to average?','Options',{'Yes','No'}); 
%                     selection_str = convertCharsToStrings(selection);
%                     
%                     if selection_str == 'Yes' %#ok<BDSCA> 
% 
%                         for rows = 1:3
%                             sum{rows,1} = sum{rows,1} + data{rows,i};
%                         end
% 
%                     else
%                         sz = sz - 1;
%                     end

                end %End loop cycling through files
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

                if isempty(app.sum)
                    uialert(app.UIFigure,"You must select at least one file to average","Select more than one");
                    return;
                end
                    

                % Now that we've looked at every file, calculate average of
                % each column. Create new cell array to hold avgs. Set w/ 0
                avgY = cell(3,1);
                avgY(:,1) = {0};

                for rows = 1:3
                    avgY{rows,1} = app.sum{rows,1}./app.sz;
                    avgY{rows,1} = num2str(avgY{rows,1}, '%.6f');
                    avgY{rows,1} = string(avgY{rows,1});
                    avgY{rows,1} = strtrim(avgY{rows,1});
                end

                % Format each block uniquely back into strings
                
                for ind=1:size(avgY{1,1},1)
                    import(ind,:) = compose('%s\t%s',lag(ind,:), avgY{1,1}(ind,:));
                end

                for ind=1:size(avgY{2,1},1)
                    import2(ind,:) = compose('%s\t%s\t%s',count(ind,:),avgY{2,1}(ind,:),avgY{3,1}(ind,:));
                end

                % Cap the end of the new data file to the minimum of the final index
                cap = min-indices(2)-1;
                import2(cap:end,:) = []; %Erase previous entries off of first read

                % Rewrite these specific blocks with new averaged data
                new(indices(1)+2:indices(2)-1) = import;
                new(indices(2)+2:min-1,1) = import2;

                new(end,:) = [];
                %Write this matrix to new .dat file
                writecell(new,join([path,"averaged","_",file(end)]));

            else
                %Not for averaging. If user selects only one file, correct them
                msgbox("Please select more than one file");
            end


        end

        % Button pushed function: YesButton
        function YesButtonPushed(app, event)
            app.TraceCountEditField.Value = app.TraceCountEditField.Value + 1;
            for rows = 1:3
                app.sum{rows,1} = app.sum{rows,1} + app.data{rows,app.index};
            end
            app.Panel.Visible = 0;
%             UserInput(app);
            uiresume(app.UIFigure);
        end

        % Button pushed function: NoButton
        function NoButtonPushed(app, event)
            app.sz = app.sz - 1;
            app.Panel.Visible = 0;
%             UserInput(app);
            uiresume(app.UIFigure);
        end

        % Callback function
        function UIFigureWindowButtonDown(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Corr = uiaxes(app.UIFigure);
            title(app.Corr, 'Correlation')
            xlabel(app.Corr, 'Lag time (s)')
            ylabel(app.Corr, 'g2-1')
            app.Corr.XScale = 'log';
            app.CHA = uiaxes(app.UIFigure);
            title(app.CHA, 'CH A')
            xlabel(app.CHA, 'Time (s)')
            ylabel(app.CHA, 'Count rate (kHz)')
            app.CHB = uiaxes(app.UIFigure);
            title(app.CHB, 'CH B')
            xlabel(app.CHB, 'Time (s)')
            ylabel(app.CHB, 'Count rate (kHz)')
            app.SelectfilesButton = uibutton(app.UIFigure, 'push');
            app.SelectfilesButton.ButtonPushedFcn = createCallbackFcn(app, @SelectfilesButtonPushed, true);
            app.SelectfilesButton.Text = 'Select files';
            app.ScatteringangleEditFieldLabel = uilabel(app.UIFigure);
            app.ScatteringangleEditFieldLabel.Text = 'Scattering angle';
            app.ScatteringangleEditField = uieditfield(app.UIFigure, 'numeric');
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Visible = 'off';
            app.TextArea = uitextarea(app.Panel);
            app.TextArea.Value = {'Do you want to add this trace to the final average?'};
            app.YesButton = uibutton(app.Panel, 'push');
            app.YesButton.ButtonPushedFcn = createCallbackFcn(app, @YesButtonPushed, true);
            app.YesButton.Text = 'Yes';
            app.NoButton = uibutton(app.Panel, 'push');
            app.NoButton.ButtonPushedFcn = createCallbackFcn(app, @NoButtonPushed, true);
            app.NoButton.Text = 'No';
            app.TraceCountEditFieldLabel = uilabel(app.Panel);
            app.TraceCountEditFieldLabel.Text = 'Trace Count';
            app.TraceCountEditField = uieditfield(app.Panel, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DLSaveraging

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end